classdef LynxApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LynxSimulatorUIFigure   matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        JointAnglesPanel        matlab.ui.container.Panel
        Theta1Deg               matlab.ui.control.NumericEditField
        Theta2Deg               matlab.ui.control.NumericEditField
        Theta3Deg               matlab.ui.control.NumericEditField
        Theta4Deg               matlab.ui.control.NumericEditField
        Theta5Deg               matlab.ui.control.NumericEditField
        RadiansLabel            matlab.ui.control.Label
        DegreesLabel            matlab.ui.control.Label
        EditFieldLabel          matlab.ui.control.Label
        Theta1Rad               matlab.ui.control.NumericEditField
        Theta2Rad               matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        Theta3Rad               matlab.ui.control.NumericEditField
        Label_2                 matlab.ui.control.Label
        Theta4Rad               matlab.ui.control.NumericEditField
        Label_3                 matlab.ui.control.Label
        Theta5Rad               matlab.ui.control.NumericEditField
        Label_4                 matlab.ui.control.Label
        EndEffectorPositionOrientationPanel  matlab.ui.container.Panel
        RollDeg                 matlab.ui.control.NumericEditField
        PitchDeg                matlab.ui.control.NumericEditField
        RadiansLabel_2          matlab.ui.control.Label
        DegreesLabel_2          matlab.ui.control.Label
        XPos                    matlab.ui.control.NumericEditField
        xEditFieldLabel         matlab.ui.control.Label
        YPos                    matlab.ui.control.NumericEditField
        yEditFieldLabel         matlab.ui.control.Label
        ZPos                    matlab.ui.control.NumericEditField
        zEditFieldLabel         matlab.ui.control.Label
        RollRad                 matlab.ui.control.NumericEditField
        RollLabel               matlab.ui.control.Label
        PitchRad                matlab.ui.control.NumericEditField
        PitchLabel              matlab.ui.control.Label
        MessageField            matlab.ui.control.TextArea
        GripperPanel            matlab.ui.container.Panel
        GripperSeparationEditFieldLabel  matlab.ui.control.Label
        GripperSeparation       matlab.ui.control.NumericEditField
        RestartAnimationButton  matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        Axes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties(Access = private)
        % The names of the STL files, in frame order
        stlFiles = {};
        
        % The input thetas, in radians
        thetas = [];
        
        % The separation of the end effector grippers
        grip;
    
        % The colors of each piece of the robot, taken from the material
        % color palette (https://material.io/resources/color)
        triColors = [
            0.8980 0.4510 0.4510
            0.7294 0.4078 0.7843
            0.3922 0.7098 0.9647
            0.3020 0.7137 0.6745
            0.6824 0.8353 0.5059
            1.0000 0.8353 0.3098
            1.0000 0.6706 0.5686
            1.0000 0.6706 0.5686
        ];
    end
    
    methods (Access = private)
        function [transformations, ee] = calcTransformations(app, thetas, grip)
            % Initialize cell array
            transformations = cell(length(app.stlFiles), 1);
            
            % Uses the joint angles to calculate and fill the
            % transformations cell array
            
            % Get DH table using the thetas
            dh = dh_table(thetas);
            
            % Reuse the same transformation to reduce the number of
            % transformation matrix calculations
            trans = Transformation.b();
            
            % The base doesn't move, so its transformation is just the
            % global origin
            transformations{1} = eye(4);
            
            for i = 1:size(app.thetas, 1)
                % Update the trans object with the particular DH table row
                trans = trans.dh(dh(i).theta, dh(i).d, dh(i).a, dh(i).alpha);
                
                % Save the current transformation matrix into the
                % transformations array
                transformations{i + 1} = trans.m();
            end
            
            % Calculate transformations for gripper 
            trans = trans.dh(pi / 2, 0, grip / 2, 0);
            transformations{7} = trans.m();
            trans = trans.dh(0, 0, -grip, 0);
            transformations{8} = trans.m();
            
            % Get EE frame and update inputs
            ee = L5forward_group2(thetas);
        end
        
        function triMaps = calcTriMaps(app, transformations)
            % Uses the transformations calculated in recalcTransformations
            % to calculate and transform the STL triangulations
            
            % Preallocate triMaps
            triMaps = cell(length(transformations), 1);
            
            for i = 1:length(app.stlFiles)
                stl = app.stlFiles{i};
                
                % Pull out points and connectivity list
                P = stl.Points;
                C = stl.ConnectivityList;
                
                % Pad the points array with 1s, and applYPos the
                % transformation matrix
                P = (transformations{i} * [P'; ones(1, length(P))])';
                
                % Remove the padding
                P = P(:, 1:3);
                
                % Construct a new triangulation, as they are read-only
                triMaps{i} = triangulation(C, P);
            end
        end
        
        function plotFromInputs(app) 
            % Update message field to provide some feedback to the user
            app.MessageField.Value = 'Replotting...';
            
            angles = [
                app.Theta1Rad.Value
                app.Theta2Rad.Value
                app.Theta3Rad.Value
                app.Theta4Rad.Value
                app.Theta5Rad.Value
            ];
        
            [transformations, ee] = app.calcTransformations(angles, app.GripperSeparation.Value);
            triMaps = app.calcTriMaps(transformations);
            
            % Update fields from ee frame
            app.XPos.Value = ee(1);
            app.YPos.Value = ee(2);
            app.ZPos.Value = ee(3);
            app.PitchRad.Value = ee(4);
            app.PitchDeg.Value = ee(4) * 180 / pi;
            app.RollRad.Value = ee(5);
            app.RollDeg.Value = ee(5) * 180 / pi;
            
            % Plot
            app.plot(triMaps);
            
            app.MessageField.Value = '';
        end
        
        function plot(app, triMaps)
            % Clear the axes
            hold(app.Axes, 'on');
            cla(app.Axes);
        
            for i = 1:length(triMaps)
                app.plotTri(triMaps{i}, app.triColors(i, :));
                
%                 if i == 6
%                     trans = app.transformations{i};
%                     
%                     lineLength = 3;
%                     lineThickness = 2;
%                     
%                     p1 = trans(1:3, 4);
%                     p2x = p1 + trans(1:3, 1) * lineLength;
%                     p2y = p1 + trans(1:3, 2) * lineLength;
%                     p2z = p1 + trans(1:3, 3) * lineLength;
%                     
%                     % Draw a little frame at the EE for easier debugging
%                     line(app.Axes, [p1(1) p2x(1)], [p1(2) p2x(2)], [p1(3) p2x(3)], 'Color', [1 0 0], 'LineWidth', lineThickness);
%                     line(app.Axes, [p1(1) p2y(1)], [p1(2) p2y(2)], [p1(3) p2y(3)], 'Color', [0 1 0], 'LineWidth', lineThickness);
%                     line(app.Axes, [p1(1) p2z(1)], [p1(2) p2z(2)], [p1(3) p2z(3)], 'Color', [0 0 1], 'LineWidth', lineThickness);
%                 end
            end
        
            % Make it look as good as possible 
            light(app.Axes, 'Position', [1.61 -1.73  1], 'Style', 'infinite');
            axis(app.Axes, 'equal');
            
            drawnow;
        end
        
        function animate(app)
            % Plots all parts of the robot using the original app input
            % thetas
            
            % Update message field to provide some feedback to the user
            app.MessageField.Value = 'Animating...';
            
            % Plot all frames
            for i = 1:size(app.thetas, 2)
                angles = app.thetas(:, i);
                [transformations, ee] = app.calcTransformations(angles, app.grip(i));
                triMaps = app.calcTriMaps(transformations);
            
                % Update fields
                app.Theta1Rad.Value = angles(1);
                app.Theta2Rad.Value = angles(2);
                app.Theta3Rad.Value = angles(3);
                app.Theta4Rad.Value = angles(4);
                app.Theta5Rad.Value = angles(5);
                app.Theta1Deg.Value = angles(1) * 180 / pi;
                app.Theta2Deg.Value = angles(2) * 180 / pi;
                app.Theta3Deg.Value = angles(3) * 180 / pi;
                app.Theta4Deg.Value = angles(4) * 180 / pi;
                app.Theta5Deg.Value = angles(5) * 180 / pi;
                app.XPos.Value = ee(1);
                app.YPos.Value = ee(2);
                app.ZPos.Value = ee(3);
                app.PitchRad.Value = ee(4);
                app.PitchDeg.Value = ee(4) * 180 / pi;
                app.RollRad.Value = ee(5);
                app.RollDeg.Value = ee(5) * 180 / pi;
                app.GripperSeparation.Value = app.grip(i);
                
                % Plot
                app.plot(triMaps);
            
                drawnow;
            end
            
            app.MessageField.Value = '';
        end
        
        function plotTri(app, tri, color)
            % Plots a triangulation with some nice effects
            p = trisurf(tri, 'FaceColor', color, ...
                             'EdgeColor', 'none', ...
                             'Parent', app.Axes);
            material(p, 'shiny');
        end


        function changeAnglesFromPosition(app)
            angles = L5inverse_group2([
                app.XPos.Value
                app.YPos.Value
                app.ZPos.Value
                app.PitchRad.Value
                app.RollRad.Value
            ]);
            app.Theta1Rad.Value = angles(1);
            app.Theta2Rad.Value = angles(2);
            app.Theta3Rad.Value = angles(3);
            app.Theta4Rad.Value = angles(4);
            app.Theta5Rad.Value = angles(5);
            app.Theta1Deg.Value = angles(1) * 180 / pi;
            app.Theta2Deg.Value = angles(2) * 180 / pi;
            app.Theta3Deg.Value = angles(3) * 180 / pi;
            app.Theta4Deg.Value = angles(4) * 180 / pi;
            app.Theta5Deg.Value = angles(5) * 180 / pi;
            app.plotFromInputs();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, thetas, grip)
            % Set app properties
            app.thetas = thetas;
            app.grip = grip;
            
            if length(app.grip) == 1
                app.GripperSeparation.Value = grip; 
                app.grip = zeros(1, size(app.thetas, 2)) + app.grip;
            end
            
            % Validate input arguments
            if size(app.thetas, 2) ~= length(app.grip)
                error('Length of grip vector must match columns of joint matrix');
            end
            
            % Update input fields with correct values
            app.Theta1Rad.Value = thetas(1);
            app.Theta2Rad.Value = thetas(2);
            app.Theta3Rad.Value = thetas(3);
            app.Theta4Rad.Value = thetas(4);
            app.Theta5Rad.Value = thetas(5);
            app.Theta1Deg.Value = thetas(1) * 180 / pi;
            app.Theta2Deg.Value = thetas(2) * 180 / pi;
            app.Theta3Deg.Value = thetas(3) * 180 / pi;
            app.Theta4Deg.Value = thetas(4) * 180 / pi;
            app.Theta5Deg.Value = thetas(5) * 180 / pi;
            
            % For some reason, the app auto-labels the X and Y axes, but
            % not the Z axis
            zlabel(app.Axes, 'Z');
            
            % Initialize app.stlFiles with the stlread function
            stlFileNames = ["Base.STL" "Frame1.STL" "Frame2.STL" "Frame3.STL" "Frame4.STL" "Frame5.STL" "LeftGripper.STL" "RightGripper.STL"];
            for i = 1:length(stlFileNames)
                app.stlFiles{i} = stlread(stlFileNames(:, i));
            end
            
            % Start animation
            app.animate();
            
            % Set a nice intro message
            app.MessageField.Value = sprintf(...
                "-- Group 2 Lynx Drawing App --\n" + ...
                "\n" + ...
                "The thetas and gripper inputs above are editable. When you change " + ...
                "one of the inputs, the plot will automatically redraw.\n" + ...
                "\n" + ...
                "Feel free to maximize this window; the app will automatically " + ...
                "resize itself to fit." ...
            );
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LynxSimulatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {590, 590};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {250, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: Theta1Rad
        function Theta1RadValueChanged(app, event)
            app.Theta1Deg.Value = app.thetas(1) * 180 / pi;
            app.plotFromInputs();
        end

        % Value changed function: Theta2Rad
        function Theta2RadValueChanged(app, event)
            app.Theta2Deg.Value = app.thetas(2) * 180 / pi;
            app.plotFromInputs();
        end

        % Value changed function: Theta3Rad
        function Theta3RadValueChanged(app, event)
            app.Theta3Deg.Value = app.thetas(3) * 180 / pi;
            app.plotFromInputs();
        end

        % Value changed function: Theta4Rad
        function Theta4RadValueChanged(app, event)
            app.Theta4Deg.Value = app.thetas(4) * 180 / pi;
            app.plotFromInputs();
        end

        % Value changed function: Theta5Rad
        function Theta5RadValueChanged(app, event)
            app.Theta5Deg.Value = app.thetas(5) * 180 / pi;
            app.plotFromInputs();
        end

        % Value changed function: Theta1Deg
        function Theta1DegValueChanged(app, event)
            app.Theta1Rad.Value = app.Theta1Deg.Value * pi / 180;
            app.plotFromInputs();
        end

        % Value changed function: Theta2Deg
        function Theta2DegValueChanged(app, event)
            app.Theta2Rad.Value = app.Theta2Deg.Value * pi / 180;
            app.plotFromInputs();
        end

        % Value changed function: Theta3Deg
        function Theta3DegValueChanged(app, event)
            app.Theta3Rad.Value = app.Theta3Deg.Value * pi / 180;
            app.plotFromInputs();
        end

        % Value changed function: Theta4Deg
        function Theta4DegValueChanged(app, event)
            app.Theta4Rad.Value = app.Theta4Deg.Value * pi / 180;
            app.plotFromInputs();
        end

        % Value changed function: Theta5Deg
        function Theta5DegValueChanged(app, event)
            app.Theta5Rad.Value = app.Theta5Deg.Value * pi / 180;
            app.plotFromInputs();
        end

        % Value changed function: GripperSeparation
        function GripperSeparationValueChanged(app, event)
            app.grip = app.GripperSeparation.Value;
            app.plotFromInputs();
        end

        % Button pushed function: RestartAnimationButton
        function RestartAnimationButtonPushed(app, event)
            app.animate();
        end

        % Value changed function: XPos
        function XPosValueChanged(app, event)
            app.changeAnglesFromPosition();
        end

        % Value changed function: YPos
        function YPosValueChanged(app, event)
            app.changeAnglesFromPosition();
        end

        % Value changed function: ZPos
        function ZPosValueChanged(app, event)
            app.changeAnglesFromPosition();
        end

        % Value changed function: RollRad
        function RollRadValueChanged(app, event)
            app.RollDeg.Value = app.RollRad.Value * 180 / pi;
            app.changeAnglesFromPosition();
        end

        % Value changed function: RollDeg
        function RollDegValueChanged(app, event)
            app.RollRad.Value = app.RollDeg.Value * pi / 180;
            app.changeAnglesFromPosition();
        end

        % Value changed function: PitchRad
        function PitchRadValueChanged(app, event)
            app.PitchDeg.Value = app.PitchRad.Value * 180 / pi;
            app.changeAnglesFromPosition();
        end

        % Value changed function: PitchDeg
        function PitchDegValueChanged(app, event)
            app.PitchRad.Value = app.PitchDeg.Value * pi / 180;
            app.changeAnglesFromPosition();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LynxSimulatorUIFigure = uifigure('Visible', 'off');
            app.LynxSimulatorUIFigure.Name = 'Lynx Simulator';
            app.LynxSimulatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LynxSimulatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.JointAnglesPanel = uipanel(app.LeftPanel);
            app.JointAnglesPanel.Title = 'Joint Angles';
            app.Theta1Deg = uieditfield(app.JointAnglesPanel, 'numeric');
            app.Theta1Deg.ValueDisplayFormat = '%.2f';
            app.Theta1Deg.ValueChangedFcn = createCallbackFcn(app, @Theta1DegValueChanged, true);
            app.Theta2Deg = uieditfield(app.JointAnglesPanel, 'numeric');
            app.Theta2Deg.ValueDisplayFormat = '%.2f';
            app.Theta2Deg.ValueChangedFcn = createCallbackFcn(app, @Theta2DegValueChanged, true);
            app.Theta3Deg = uieditfield(app.JointAnglesPanel, 'numeric');
            app.Theta3Deg.ValueDisplayFormat = '%.2f';
            app.Theta3Deg.ValueChangedFcn = createCallbackFcn(app, @Theta3DegValueChanged, true);
            app.Theta4Deg = uieditfield(app.JointAnglesPanel, 'numeric');
            app.Theta4Deg.ValueDisplayFormat = '%.2f';
            app.Theta4Deg.ValueChangedFcn = createCallbackFcn(app, @Theta4DegValueChanged, true);
            app.Theta5Deg = uieditfield(app.JointAnglesPanel, 'numeric');
            app.Theta5Deg.ValueDisplayFormat = '%.2f';
            app.Theta5Deg.ValueChangedFcn = createCallbackFcn(app, @Theta5DegValueChanged, true);
            app.RadiansLabel = uilabel(app.JointAnglesPanel);
            app.RadiansLabel.Text = 'Radians';
            app.DegreesLabel = uilabel(app.JointAnglesPanel);
            app.DegreesLabel.Text = 'Degrees';
            app.EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.EditFieldLabel.Text = 'θ1';
            app.Theta1Rad = uieditfield(app.JointAnglesPanel, 'numeric');
            app.Theta1Rad.ValueDisplayFormat = '%.2f';
            app.Theta1Rad.ValueChangedFcn = createCallbackFcn(app, @Theta1RadValueChanged, true);
            app.Theta2Rad = uieditfield(app.JointAnglesPanel, 'numeric');
            app.Theta2Rad.ValueDisplayFormat = '%.2f';
            app.Theta2Rad.ValueChangedFcn = createCallbackFcn(app, @Theta2RadValueChanged, true);
            app.Label = uilabel(app.JointAnglesPanel);
            app.Label.Text = 'θ2';
            app.Theta3Rad = uieditfield(app.JointAnglesPanel, 'numeric');
            app.Theta3Rad.ValueDisplayFormat = '%.2f';
            app.Theta3Rad.ValueChangedFcn = createCallbackFcn(app, @Theta3RadValueChanged, true);
            app.Label_2 = uilabel(app.JointAnglesPanel);
            app.Label_2.Text = 'θ3';
            app.Theta4Rad = uieditfield(app.JointAnglesPanel, 'numeric');
            app.Theta4Rad.ValueDisplayFormat = '%.2f';
            app.Theta4Rad.ValueChangedFcn = createCallbackFcn(app, @Theta4RadValueChanged, true);
            app.Label_3 = uilabel(app.JointAnglesPanel);
            app.Label_3.Text = 'θ4';
            app.Theta5Rad = uieditfield(app.JointAnglesPanel, 'numeric');
            app.Theta5Rad.ValueDisplayFormat = '%.2f';
            app.Theta5Rad.ValueChangedFcn = createCallbackFcn(app, @Theta5RadValueChanged, true);
            app.Label_4 = uilabel(app.JointAnglesPanel);
            app.Label_4.Text = 'θ5';
            app.EndEffectorPositionOrientationPanel = uipanel(app.LeftPanel);
            app.EndEffectorPositionOrientationPanel.Title = 'End Effector Position & Orientation';
            app.RollDeg = uieditfield(app.EndEffectorPositionOrientationPanel, 'numeric');
            app.RollDeg.ValueDisplayFormat = '%.2f';
            app.RollDeg.ValueChangedFcn = createCallbackFcn(app, @RollDegValueChanged, true);
            app.PitchDeg = uieditfield(app.EndEffectorPositionOrientationPanel, 'numeric');
            app.PitchDeg.ValueDisplayFormat = '%.2f';
            app.PitchDeg.ValueChangedFcn = createCallbackFcn(app, @PitchDegValueChanged, true);
            app.RadiansLabel_2 = uilabel(app.EndEffectorPositionOrientationPanel);
            app.RadiansLabel_2.Text = 'Radians';
            app.DegreesLabel_2 = uilabel(app.EndEffectorPositionOrientationPanel);
            app.DegreesLabel_2.Text = 'Degrees';
            app.XPos = uieditfield(app.EndEffectorPositionOrientationPanel, 'numeric');
            app.XPos.ValueDisplayFormat = '%.2f';
            app.XPos.ValueChangedFcn = createCallbackFcn(app, @XPosValueChanged, true);
            app.xEditFieldLabel = uilabel(app.EndEffectorPositionOrientationPanel);
            app.xEditFieldLabel.Text = 'x';
            app.YPos = uieditfield(app.EndEffectorPositionOrientationPanel, 'numeric');
            app.YPos.ValueDisplayFormat = '%.2f';
            app.YPos.ValueChangedFcn = createCallbackFcn(app, @YPosValueChanged, true);
            app.yEditFieldLabel = uilabel(app.EndEffectorPositionOrientationPanel);
            app.yEditFieldLabel.Text = 'y';
            app.ZPos = uieditfield(app.EndEffectorPositionOrientationPanel, 'numeric');
            app.ZPos.ValueDisplayFormat = '%.2f';
            app.ZPos.ValueChangedFcn = createCallbackFcn(app, @ZPosValueChanged, true);
            app.zEditFieldLabel = uilabel(app.EndEffectorPositionOrientationPanel);
            app.zEditFieldLabel.Text = 'z';
            app.RollRad = uieditfield(app.EndEffectorPositionOrientationPanel, 'numeric');
            app.RollRad.ValueDisplayFormat = '%.2f';
            app.RollRad.ValueChangedFcn = createCallbackFcn(app, @RollRadValueChanged, true);
            app.RollLabel = uilabel(app.EndEffectorPositionOrientationPanel);
            app.RollLabel.Text = 'Roll';
            app.PitchRad = uieditfield(app.EndEffectorPositionOrientationPanel, 'numeric');
            app.PitchRad.ValueDisplayFormat = '%.2f';
            app.PitchRad.ValueChangedFcn = createCallbackFcn(app, @PitchRadValueChanged, true);
            app.PitchLabel = uilabel(app.EndEffectorPositionOrientationPanel);
            app.PitchLabel.Text = 'Pitch';
            app.MessageField = uitextarea(app.LeftPanel);
            app.MessageField.Editable = 'off';
            app.GripperPanel = uipanel(app.LeftPanel);
            app.GripperPanel.Title = 'Gripper';
            app.GripperSeparationEditFieldLabel = uilabel(app.GripperPanel);
            app.GripperSeparationEditFieldLabel.Text = 'Gripper Separation';
            app.GripperSeparation = uieditfield(app.GripperPanel, 'numeric');
            app.GripperSeparation.Limits = [0 3.55];
            app.GripperSeparation.ValueChangedFcn = createCallbackFcn(app, @GripperSeparationValueChanged, true);
            app.RestartAnimationButton = uibutton(app.LeftPanel, 'push');
            app.RestartAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @RestartAnimationButtonPushed, true);
            app.RestartAnimationButton.Text = 'Restart Animation';
            app.RightPanel = uipanel(app.GridLayout);
            app.Axes = uiaxes(app.RightPanel);
            title(app.Axes, '')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            % Show the figure after all components are created
            app.LynxSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LynxApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LynxSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)init(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LynxSimulatorUIFigure)
        end
    end
end